classdef BEM_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Back      matlab.ui.control.Button
        title     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            R=app.CallingApp.R
            z=app.CallingApp.z
            v=app.CallingApp.v
            prof=app.CallingApp.prof
            [lambda,omega,n,M,P,T,CM,CP,CT]=characteristics(v,R,z,app.CallingApp.prof)
           
            t=tiledlayout(app.Panel,3,2)
            
            ax1=nexttile(t)
            plot(ax1,lambda,CP)
             title(ax1,'C_P-\lambda curve')
             xlabel(ax1,'\lambda')
             ylabel(ax1,'C_P')
             axis (ax1,[1 14 0 Inf])
             
             ax2=nexttile(t)
            plot(ax2,n,P)
            title (ax2,'P-n curve')
            xlabel(ax2,'n [rpm]')
            ylabel(ax2,'Power [W]')

            
            ax3=nexttile(t)
            plot(ax3,lambda,CM)
            title(ax3,'C_M-\lambda curve')
            xlabel(ax3,'\lambda')
            ylabel(ax3,'C_M')
            axis (ax3,[1 14 0 Inf])
            
            ax4=nexttile(t)
            plot(ax4,n,M)
            title(ax4,'M-n curve')
            xlabel(ax4,'n [rpm]')
            ylabel(ax4,'Torque [Nm]')
            
            ax5=nexttile(t)
            plot(ax5,lambda,CT)
            title(ax5,'C_T-\lambda curve')
            xlabel(ax5,'\lambda')
            ylabel(ax5,'C_T')
            axis (ax5,[1 14 0 Inf])
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
             app.CallingApp.BEMButton.Enable = 'on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Blade polars';
            app.Back = uibutton(app.UIFigure, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Text = 'back';
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BEM_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end