classdef Blade < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BladeDesignerUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        RemovefoilButton       matlab.ui.control.Button
        AddfoilButton          matlab.ui.control.Button
        Load_Button            matlab.ui.control.Button
        Save_Button            matlab.ui.control.Button
        BEMButton              matlab.ui.control.Button
        Export3DButton         matlab.ui.control.Button
        start_init             matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        BladeDesignerLabel     matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        windspeed              matlab.ui.control.NumericEditField
        LngedBlattmLabel_2     matlab.ui.control.Label
        PreviewLabel           matlab.ui.control.Label
        ParametersLabel        matlab.ui.control.Label
        number_blades          matlab.ui.control.NumericEditField
        BlattanzahlLabel       matlab.ui.control.Label
        lam                    matlab.ui.control.NumericEditField
        TipspeedratioLabel     matlab.ui.control.Label
        blade_radius           matlab.ui.control.NumericEditField
        LngedBlattmLabel       matlab.ui.control.Label
        table                  matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
        H                      matlab.ui.control.NumericEditField
        HeightofTowerLabel     matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        DialogApp
    end
    
    
    properties (Access = public)
        foil_db_path="foil_imported.dat";
       foil_db=readtable("foils_imported.dat")
       lambda=7;
       R=50;
       z=3;
       v=6;
       H_tower=0;
       prof;
    end
    
    methods (Access = public)
        function add_blade(app,name,r,t)
            if length (app.prof)==0
                app.R
                app.prof=blade_root(app.R);
                if r>= 0.15*app.R
                    app.prof(3)=foil_transform(app.R,r,name,app.lambda,app.z, t,app.foil_db)
                else
                    disp('Profil ist zu nah an der Blattwurzel. Anderen Profilposition waehlen!')    
                end       
            else
                if check_existing(app.prof,r) == 0 & r>= 0.15*app.R
                    l=length(app.prof);
                    app.prof(l+1)=foil_transform(app.R,r,name,app.lambda,app.z, t,app.foil_db);
                    app.prof=bld_sort(app.prof);
                else
                    disp('Profil an dieser Blattposition existiert bereits oder ist zu nah an der Blattwurzel. Anderen Profilposition waehlen!')
                end
            end
            
            blade_plot(app)
            update_table(app)
        end
      
        function  blade_plot(app)
            %%Plotet einen Satz profile in 3d
            l=length (app.prof)
            for n=1:l
                plot3(app.UIAxes ,app.prof(n).z, app.prof(n).y, app.prof(n).x,'k');
                hold( app.UIAxes, 'on' )
                axis (app.UIAxes, 'equal' ) 
                grid (app.UIAxes, 'on' ) 
                text(app.UIAxes ,app.prof(n).z(1), app.prof(n).y(1), app.prof(n).x(1)+1,app.prof(n).name)
            end
        hold( app.UIAxes, 'off' )
        end 
        
        function update_table(app)
            t=struct2table(app.prof);
            t.name=char(t.name)
            t=removevars(t,{'x','y','z'})
            t.name=string(t.name)
            t=t(:,{'name','r','alpha_bau','camber','Cl','Cd'})
            app.table.ColumnName=t.Properties.VariableNames;
            app.table.Data=t
        end


        function  update_values(app)
            app.blade_radius.Value=app.R
             app.lam.Value=app.lambda
             app.number_blades.Value=app.z
             app.windspeed.Value=app.v
             app.R
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       %%update_table(app)
        end

        % Button pushed function: start_init
        function start_initPushed(app, event)
            app.DialogApp = blade_init(app);
            app.start_init.Enable='off';
        end

        % Button pushed function: Export3DButton
        function Export3DButtonPushed(app, event)
           
           addpath("Tool\");
            blade_export(app.prof,app.R,app.z);
            Matlab_call;
        end

        % Button pushed function: Save_Button
        function Save_ButtonPushed(app, event)
            filter = {'*.bld';'*.*'};
            [file,path,indx] = uiputfile(filter);
            blade_save(app.prof,path,file)
            disp('file was written')
        end

        % Button pushed function: Load_Button
        function Load_ButtonPushed(app, event)
            addpath("yamlmatlab\")
            app.prof=[]
            filter = {'*.bld';'*.*'};
            [file,path]=uigetfile(filter)
            filepath=join([path,file])
            app.prof=blade_load(filepath)
            
            app.R=app.prof(1).R
            app.lambda=app.prof(1).lambda
            app.z=app.prof(1).z_bld
            
            blade_plot(app)
            update_table(app)
            update_values(app)
        end

        % Button pushed function: AddfoilButton
        function AddfoilButtonPushed(app, event)
             app.DialogApp = BladeIn(app);
            app.AddfoilButton.Enable = 'off';
        end

        % Button pushed function: RemovefoilButton
        function RemovefoilButtonPushed(app, event)
            app.DialogApp = blade_rem(app);
            app.RemovefoilButton.Enable='off';
        end

        % Button pushed function: BEMButton
        function BEMButtonPushed(app, event)
            app.DialogApp = BEM_plot(app);
            app.BEMButton.Enable='off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BladeDesignerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {705, 705};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BladeDesignerUIFigure = uifigure('Visible', 'off');
            app.BladeDesignerUIFigure.Name = 'Blade Designer';
            app.BladeDesignerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BladeDesignerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BladeDesignerLabel = uilabel(app.LeftPanel);
            app.BladeDesignerLabel.Text = 'Blade Designer';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Blatt.png';
            app.start_init = uibutton(app.LeftPanel, 'push');
            app.start_init.ButtonPushedFcn = createCallbackFcn(app, @start_initPushed, true);
            app.start_init.Text = 'New Blade';
            app.Export3DButton = uibutton(app.LeftPanel, 'push');
            app.Export3DButton.ButtonPushedFcn = createCallbackFcn(app, @Export3DButtonPushed, true);
            app.Export3DButton.Text = 'Export 3D';
            app.BEMButton = uibutton(app.LeftPanel, 'push');
            app.BEMButton.ButtonPushedFcn = createCallbackFcn(app, @BEMButtonPushed, true);
            app.BEMButton.Text = 'BEM';
            app.Save_Button = uibutton(app.LeftPanel, 'push');
            app.Save_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_ButtonPushed, true);
            app.Save_Button.Text = 'Save Blade';
            app.Load_Button = uibutton(app.LeftPanel, 'push');
            app.Load_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_ButtonPushed, true);
            app.Load_Button.Text = 'Load Blade';
            app.AddfoilButton = uibutton(app.LeftPanel, 'push');
            app.AddfoilButton.ButtonPushedFcn = createCallbackFcn(app, @AddfoilButtonPushed, true);
            app.AddfoilButton.Text = 'Add foil';
            app.RemovefoilButton = uibutton(app.LeftPanel, 'push');
            app.RemovefoilButton.ButtonPushedFcn = createCallbackFcn(app, @RemovefoilButtonPushed, true);
            app.RemovefoilButton.Text = 'Remove foil';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Z')
            ylabel(app.UIAxes, 'X')
            zlabel(app.UIAxes, 'Y')
            app.table = uitable(app.RightPanel);
            app.table.ColumnName = {'name'; 'r'; 'alpha_bau'; 'camber'; 'Cl'; 'Cd'};
            app.table.RowName = {};
            app.table.ColumnSortable = [false true false false true false];
            app.table.SelectionType = 'row';
            app.LngedBlattmLabel = uilabel(app.RightPanel);
            app.LngedBlattmLabel.Text = 'Radius of rotor [m]';
            app.blade_radius = uieditfield(app.RightPanel, 'numeric');
            app.blade_radius.Limits = [0 Inf];
            app.blade_radius.ValueDisplayFormat = '%.1f';
            app.blade_radius.Editable = 'off';
            app.TipspeedratioLabel = uilabel(app.RightPanel);
            app.TipspeedratioLabel.Text = 'Tip-speed ratio';
            app.lam = uieditfield(app.RightPanel, 'numeric');
            app.lam.Limits = [0 Inf];
            app.lam.ValueDisplayFormat = '%.1f';
            app.lam.Editable = 'off';
            app.BlattanzahlLabel = uilabel(app.RightPanel);
            app.BlattanzahlLabel.Text = 'Number of blades';
            app.number_blades = uieditfield(app.RightPanel, 'numeric');
            app.number_blades.Limits = [0 Inf];
            app.number_blades.ValueDisplayFormat = '%.0f';
            app.number_blades.Editable = 'off';
            app.ParametersLabel = uilabel(app.RightPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.PreviewLabel = uilabel(app.RightPanel);
            app.PreviewLabel.Text = 'Preview';
            app.LngedBlattmLabel_2 = uilabel(app.RightPanel);
            app.LngedBlattmLabel_2.Text = 'Wind speed [m/s]';
            app.windspeed = uieditfield(app.RightPanel, 'numeric');
            app.windspeed.Limits = [0 Inf];
            app.windspeed.ValueDisplayFormat = '%.1f';
            app.windspeed.Editable = 'off';
            app.HeightofTowerLabel = uilabel(app.BladeDesignerUIFigure);
            app.HeightofTowerLabel.Text = 'Height of Tower [m]';
            app.H = uieditfield(app.BladeDesignerUIFigure, 'numeric');
            app.H.Limits = [0 Inf];
            app.H.ValueDisplayFormat = '%.2f';
            app.H.Editable = 'off';
            % Show the figure after all components are created
            app.BladeDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Blade

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BladeDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BladeDesignerUIFigure)
        end
    end
end