classdef BladeIn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AddingAirfoilUIFigure  matlab.ui.Figure
        ratio                  matlab.ui.control.Label
        title                  matlab.ui.control.Label
        addsafoilatgivenlocationonthebladeLabel  matlab.ui.control.Label
        t                      matlab.ui.control.NumericEditField
        profilaxisLabel        matlab.ui.control.Label
        Profil                 matlab.ui.control.DropDown
        AirfoilDropDownLabel   matlab.ui.control.Label
        Entfern                matlab.ui.control.Button
        mLabel                 matlab.ui.control.Label
        Hinzu                  matlab.ui.control.Button
        dr                     matlab.ui.control.NumericEditField
        PositionLabel          matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp 
    end
    
    properties (Access = public)
    
    end
    
    methods (Access = private)
        function plot_foil(app,name)
            filepath=join(['imported\',name,'_coords.dat'])
            A=readmatrix(filepath);
            plot(app.UIAxes,A(:,1),A(:,2))
            
            mx=max(A(:,2))
            mn=min(A(:,2))
            ratio=1/(mx-mn)
            text=join(['Aspect Ratio : ', num2str(ratio)])
            
            app.ratio.Text=text
            axis (app.UIAxes, 'equal' )
            title (app.UIAxes, name )
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;


            app.Profil.Items= string(mainapp.foil_db.foil_name)
    %        app.addsafoilatgivenlocationonthebladeLabel.Text=app.Profil.Value
            plot_foil(app,app.Profil.Value)   
            app.CallingApp.AddfoilButton.Enable = 'on';
            % app.dr.Value=app.CallingApp.R
        end

        % Button pushed function: Entfern
        function EntfernButtonPushed(app, event)
%           app.CallingApp.OptionsButton.Enable = 'on';
            app.CallingApp.AddfoilButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: Hinzu
        function HinzuButtonPushed(app, event)
           if app.dr.Value <= app.CallingApp.R &  app.dr.Value >app.CallingApp.R*0.2 
                add_blade(app.CallingApp, app.Profil.Value,app.dr.Value,app.t.Value)   
           else
               f = msgbox("Radius Value out of range. Please choose a valid raddius value");
           end
        end

        % Value changed function: Profil
        function ProfilValueChanged(app, event)

           %% app.addsafoilatgivenlocationonthebladeLabel.Text=app.Profil.Value
            plot_foil(app,app.Profil.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AddingAirfoilUIFigure = uifigure('Visible', 'off');
            app.AddingAirfoilUIFigure.Name = 'Adding Airfoil';
            app.UIAxes = uiaxes(app.AddingAirfoilUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PositionLabel = uilabel(app.AddingAirfoilUIFigure);
            app.PositionLabel.Text = 'at the Radius of';
            app.dr = uieditfield(app.AddingAirfoilUIFigure, 'numeric');
            app.dr.Limits = [0 Inf];
            app.dr.ValueDisplayFormat = '%.2f';
            app.dr.Value = 15;
            app.Hinzu = uibutton(app.AddingAirfoilUIFigure, 'push');
            app.Hinzu.ButtonPushedFcn = createCallbackFcn(app, @HinzuButtonPushed, true);
            app.Hinzu.Icon = 'plus.png';
            app.Hinzu.Text = 'Add foil';
            app.mLabel = uilabel(app.AddingAirfoilUIFigure);
            app.mLabel.Text = '[  m  ]';
            app.Entfern = uibutton(app.AddingAirfoilUIFigure, 'push');
            app.Entfern.ButtonPushedFcn = createCallbackFcn(app, @EntfernButtonPushed, true);
            app.Entfern.Icon = 'back.png';
            app.Entfern.Text = 'Back';
            app.AirfoilDropDownLabel = uilabel(app.AddingAirfoilUIFigure);
            app.AirfoilDropDownLabel.Text = 'Airfoil';
            app.Profil = uidropdown(app.AddingAirfoilUIFigure);
            app.Profil.Items = {'NACA0012', 'NACA0014'};
            app.Profil.ValueChangedFcn = createCallbackFcn(app, @ProfilValueChanged, true);
            app.Profil.Value = 'NACA0012';
            app.profilaxisLabel = uilabel(app.AddingAirfoilUIFigure);
            app.profilaxisLabel.Text = 'profil axis';
            app.t = uieditfield(app.AddingAirfoilUIFigure, 'numeric');
            app.t.Limits = [0 1];
            app.t.ValueDisplayFormat = '%.2f';
            app.t.Value = 0.25;
            app.addsafoilatgivenlocationonthebladeLabel = uilabel(app.AddingAirfoilUIFigure);
            app.addsafoilatgivenlocationonthebladeLabel.Text = 'adds a foil at given location on the blade';
            app.title = uilabel(app.AddingAirfoilUIFigure);
            app.title.Text = 'adds Airfoils to current Blade';
            app.ratio = uilabel(app.AddingAirfoilUIFigure);
            app.ratio.Text = 'Aspect Ratio : ';
            % Show the figure after all components are created
            app.AddingAirfoilUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BladeIn(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AddingAirfoilUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AddingAirfoilUIFigure)
        end
    end
end