classdef blade_init < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        v                              matlab.ui.control.NumericEditField
        WindsspeedvmsLabel             matlab.ui.control.Label
        startsanewbladeandcreatestherootgeometryLabel  matlab.ui.control.Label
        Accept                         matlab.ui.control.Button
        z                              matlab.ui.control.NumericEditField
        NumberofBladeszLabel           matlab.ui.control.Label
        lambda                         matlab.ui.control.NumericEditField
        TipspeedratioSchnelllaufzahllambdaEditFieldLabel  matlab.ui.control.Label
        R                              matlab.ui.control.NumericEditField
        RadiusoftheRotorRmEditFieldLabel  matlab.ui.control.Label
        InitialvaluesforthebladeLabel  matlab.ui.control.Label
        Back                           matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp  % Description
    end
    
    methods (Access = public)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Button pushed function: Back
        function BackButtonPushed(app, event)
            app.CallingApp.start_init.Enable='on';
            delete(app)
        end

        % Button pushed function: Accept
        function AcceptButtonPushed(app, event)
            app.CallingApp.R=app.R.Value;
            app.CallingApp.z=app.z.Value;
            app.CallingApp.lambda=app.lambda.Value;
            app.CallingApp.v=app.v.Value
            app.CallingApp.prof=[]
            
            app.CallingApp.prof=blade_root(app.R.Value,app.lambda.Value,app.z.Value);
            
            update_values(app.CallingApp)
            update_table(app.CallingApp)
            blade_plot(app.CallingApp) 
            
            app.CallingApp.start_init.Enable='on';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Back = uibutton(app.UIFigure, 'push');
            app.Back.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.Back.Text = 'Back';
            app.InitialvaluesforthebladeLabel = uilabel(app.UIFigure);
            app.InitialvaluesforthebladeLabel.Text = 'Initial values for the blade';
            app.RadiusoftheRotorRmEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusoftheRotorRmEditFieldLabel.Text = 'Radius of the Rotor R [m]';
            app.R = uieditfield(app.UIFigure, 'numeric');
            app.R.Value = 50;
            app.TipspeedratioSchnelllaufzahllambdaEditFieldLabel = uilabel(app.UIFigure);
            app.TipspeedratioSchnelllaufzahllambdaEditFieldLabel.Text = 'Tip speed ratio (Schnelllaufzahl) \lambda';
            app.lambda = uieditfield(app.UIFigure, 'numeric');
            app.lambda.Value = 7;
            app.NumberofBladeszLabel = uilabel(app.UIFigure);
            app.NumberofBladeszLabel.Text = 'Number of Blades z';
            app.z = uieditfield(app.UIFigure, 'numeric');
            app.z.Value = 3;
            app.Accept = uibutton(app.UIFigure, 'push');
            app.Accept.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.Accept.Text = 'Accept';
            app.startsanewbladeandcreatestherootgeometryLabel = uilabel(app.UIFigure);
            app.startsanewbladeandcreatestherootgeometryLabel.Text = 'starts a new blade and creates the root geometry';
            app.WindsspeedvmsLabel = uilabel(app.UIFigure);
            app.WindsspeedvmsLabel.Text = 'Winds speed v  [m/s]';
            app.v = uieditfield(app.UIFigure, 'numeric');
            app.v.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blade_init(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end