classdef blade_rem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label                        matlab.ui.control.Label
        info                         matlab.ui.control.Label
        title                        matlab.ui.control.Label
        backButton                   matlab.ui.control.Button
        removeButton                 matlab.ui.control.Button
        r_remove                     matlab.ui.control.NumericEditField
        radiusonbladeEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
             app.CallingApp = mainapp;
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            app.CallingApp.RemovefoilButton.Enable = 'on';
            delete(app)
        end

        % Button pushed function: removeButton
        function removeButtonPushed(app, event)
            radi=[app.CallingApp.prof.r];
            app.Label.Text=num2str(radi(1,1));
            index=find(radi==app.r_remove.Value);
            if length(index)==1
                app.CallingApp.prof(index)=[];
                blade_plot(app.CallingApp);
                update_table(app.CallingApp);
                app.Label.Text='';
            else
                app.Label.Text='No foil at given location';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.radiusonbladeEditFieldLabel = uilabel(app.UIFigure);
            app.radiusonbladeEditFieldLabel.Text = 'radius on blade';
            app.r_remove = uieditfield(app.UIFigure, 'numeric');
            app.removeButton = uibutton(app.UIFigure, 'push');
            app.removeButton.ButtonPushedFcn = createCallbackFcn(app, @removeButtonPushed, true);
            app.removeButton.Text = 'remove';
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Text = 'back';
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Remove Airfoil from current Blade';
            app.info = uilabel(app.UIFigure);
            app.info.Tag = 'label2';
            app.info.Text = {'removes a foil entry in the current blade'; ' specified by it location on the blade'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blade_rem(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end